classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRVEvaluationToolUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ListBox                     matlab.ui.control.ListBox
        DropDown                    matlab.ui.control.DropDown
        SetstressintervalPanel      matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        LengthminEditField          matlab.ui.control.NumericEditField
        LengthminEditFieldLabel     matlab.ui.control.Label
        StarttimeminEditField       matlab.ui.control.NumericEditField
        StarttimeminEditFieldLabel  matlab.ui.control.Label
        nomeasurementselectedLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        OpenMenu                    matlab.ui.container.Menu
        CreateparametersettingscsvfileMenu  matlab.ui.container.Menu
        LoadmeasurementMenu         matlab.ui.container.Menu
        PlotMenu                    matlab.ui.container.Menu
        SaveplotMenu                matlab.ui.container.Menu
        RestoreviewMenu             matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        StepbystepguideMenu         matlab.ui.container.Menu
        ContactMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        data                % the mat-file that has been loaded
        setup               % the setup mat-file
        createSampleFile    % the popup to create sample config from
        stressIntervall     % the intervall that is highlighted red
    end
    
    methods (Access = public)
        % import data from sample configuration app and call the csv
        % creation function
        function importdata(~, sampleStart, sampleLength, fileLength, sampleShift, filename, path)
             % create the csv
             if ~createCSV(sampleStart, sampleLength, fileLength, sampleShift, filename, path)
                msgbox("Something went wrong, please try again!");
             end
             % automatically start Kubios HRV Premium on MacOS
             if isunix
                [status, ~] = system("cd /; open Applications/" + ...
                    "KubiosHRVPremium/application/KubiosHRVPremium.app");
                if status
                    msgbox("There was an issue starting Kubios HRV Premium! Please try it manually.");
                end
             end
             if ispc
                % TODO: add Windows Support here
             end
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setupApp(app)
            % load the configuration file of all variables
            app.setup = load('assets/setup.mat');
            % update the list box
            app.changeVariableGroup();
        end

        % Menu selected function: LoadmeasurementMenu
        function selectFile(app, event)
            % deactivate buttons while loading file
            app.ListBox.Enable = "off";
            
            % open file and parse it
            [file, path] = uigetfile('*.mat');
            path2file = fullfile(path, file);
            app.data = WithSamplesParser(path2file);
            
            % call function to plot data after file is loaded
            app.plotBySelectingListItem();
            % show name of selected measurement file
            set(app.nomeasurementselectedLabel, 'Text', file);
            
            % enable variable selection
            app.ListBox.Enable = "on";
            app.DropDown.Enable = "on";   
        end

        % Close request function: HRVEvaluationToolUIFigure
        function checkIfSaved(app, event)
            % when closing the app, ask if th user has saved its plot

            fig = app.HRVEvaluationToolUIFigure;
            msg = ['Did you save your evaluation? Otherwise it will' ...
                ' not be saved.'];
            title = 'Evaluation saved?';
            selection = uiconfirm(fig,msg,title, ...
           'Options',{'Yes','No'}, 'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'Yes')
                delete(app)
            end
        end

        % Menu selected function: CreateparametersettingscsvfileMenu
        function createExportFile(app, event)
            % call the sample configuration app
            
            app.createSampleFile = createSampleFile(app);
        end

        % Menu selected function: SaveplotMenu
        function savePlot(app, event)
            % this functions saves the plot to a given format
            % it can be called in the menu bar
            
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes, [filepath filename]);
            end
        end

        % Menu selected function: RestoreviewMenu
        function restorePlotView(app, event)
            % restore the view of the plot
            zoom(app.UIAxes, 'out');
        end

        % Value changed function: Switch
        function toggleStressIntervall(app, event)
            % this function adds/removes the stress intervall by the given
            % configuration

            value = app.Switch.Value;
            if strcmp(value, 'On')
                % calculate height and width of stress intervall
                rectX = [app.StarttimeminEditField.Value, app.StarttimeminEditField.Value + app.LengthminEditField.Value];
                rectY = ylim(app.UIAxes);
                % add stress intervall to plot
                app.stressIntervall = patch(app.UIAxes, rectX([1,2,2,1]), rectY([1 1 2 2]), 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.2);
                % reset limits of UIAxes to prevent layout issues
                ylim(app.UIAxes, rectY);
            else
                % delete stress intervall if switch is set to off
                delete(app.stressIntervall);
            end
        end

        % Value changed function: DropDown
        function changeVariableGroup(app, event)
            % automatically plot the pre-selected variable after changing
            % the group
            value = app.DropDown.Value;
            
            % change content of list box to the elements of the selected group
            if value == "Time-Domain"
                app.ListBox.Items = {app.setup.variablesStruct.timeDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.timeDomain(:).index};
            elseif value =="Frequency-Domain FFT spectrum"
                app.ListBox.Items = {app.setup.variablesStruct.frequencyDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.frequencyDomain(:).index};
            elseif value =="Frequency-Domain AR spectrum"
                app.ListBox.Items = {app.setup.variablesStruct.frequencyDomain(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.frequencyDomain(:).index};
            elseif value =="Nonlinear"
                app.ListBox.Items = {app.setup.variablesStruct.nonlinear(:).short};
                app.ListBox.ItemsData = {app.setup.variablesStruct.nonlinear(:).index};
            end
        end

        % Value changed function: ListBox
        function plotBySelectingListItem(app, event)
            % this function is called by selecting an item in the list box
            % on the left hand side and plots the selected variable

            value = app.ListBox.Value;
            % call the plot function of the Parser class
            app.data.plotToAxes(app.setup, app.UIAxes, value, app.DropDown.Value);
            % keep stress intervall set after changing variable
            app.toggleStressIntervall();
        end

        % Menu selected function: ContactMenu
        function openContact(app, event)
            % this function can be called in the menu bar to return a pop
            % up window that contains contact informations

            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            f = msgbox(["\bf\fontsize{10} Tim Weiß:"; "\rm\fontsize{9} timweiss01@gmx.de"; "\bf\fontsize{10} Jona Krumrein:"; "\rm\fontsize{9} krumrein.jona@gmail.com"], "Contact", "help", CreateStruct);
           
            text = findall(f, 'Type', 'Text');
            deltaWidth = sum(text.Extent([1,3]))-f.Position(3) + text.Extent(1);
            deltaHeight = sum(text.Extent([2,4]))-f.Position(4) + 10;
            f.Position([3,4]) = f.Position([3,4]) + [deltaWidth, deltaHeight];
        end

        % Menu selected function: StepbystepguideMenu
        function openGuide(app, event)
            % open the stepByStepGuide from the menu bar
            winopen("assets\guide.pdf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HRVEvaluationToolUIFigure = uifigure('Visible', 'off');
            app.HRVEvaluationToolUIFigure.Name = 'HRV Evaluation Tool';
            app.HRVEvaluationToolUIFigure.Icon = 'HRVEvalToolIcon.png';
            app.HRVEvaluationToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @checkIfSaved, true);
            app.GridLayout = uigridlayout(app.HRVEvaluationToolUIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes, 'Z')
            app.nomeasurementselectedLabel = uilabel(app.GridLayout2);
            app.nomeasurementselectedLabel.Text = 'no measurement selected';
            app.SetstressintervalPanel = uipanel(app.GridLayout2);
            app.SetstressintervalPanel.Title = 'Set stress interval';
            app.StarttimeminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.StarttimeminEditFieldLabel.Text = 'Start time (min)';
            app.StarttimeminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.StarttimeminEditField.Limits = [0 Inf];
            app.LengthminEditFieldLabel = uilabel(app.SetstressintervalPanel);
            app.LengthminEditFieldLabel.Text = 'Length (min)';
            app.LengthminEditField = uieditfield(app.SetstressintervalPanel, 'numeric');
            app.LengthminEditField.Limits = [0 Inf];
            app.Switch = uiswitch(app.SetstressintervalPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @toggleStressIntervall, true);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Time-Domain', 'Frequency-Domain FFT spectrum', 'Frequency-Domain AR spectrum', 'Nonlinear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @changeVariableGroup, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Time-Domain';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Mean RR', 'Stress index', ''};
            app.ListBox.ItemsData = {'Mean RR', 'Stress index', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @plotBySelectingListItem, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Mean RR';
            app.OpenMenu = uimenu(app.HRVEvaluationToolUIFigure);
            app.OpenMenu.Text = 'Open';
            app.CreateparametersettingscsvfileMenu = uimenu(app.OpenMenu);
            app.CreateparametersettingscsvfileMenu.MenuSelectedFcn = createCallbackFcn(app, @createExportFile, true);
            app.CreateparametersettingscsvfileMenu.Separator = 'on';
            app.CreateparametersettingscsvfileMenu.Text = 'Create parametersettings csv-file';
            app.LoadmeasurementMenu = uimenu(app.OpenMenu);
            app.LoadmeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @selectFile, true);
            app.LoadmeasurementMenu.Text = 'Load measurement';
            app.PlotMenu = uimenu(app.HRVEvaluationToolUIFigure);
            app.PlotMenu.Text = 'Plot';
            app.SaveplotMenu = uimenu(app.PlotMenu);
            app.SaveplotMenu.MenuSelectedFcn = createCallbackFcn(app, @savePlot, true);
            app.SaveplotMenu.Text = 'Save plot';
            app.RestoreviewMenu = uimenu(app.PlotMenu);
            app.RestoreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @restorePlotView, true);
            app.RestoreviewMenu.Text = 'Restore view';
            app.HelpMenu = uimenu(app.HRVEvaluationToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.StepbystepguideMenu = uimenu(app.HelpMenu);
            app.StepbystepguideMenu.MenuSelectedFcn = createCallbackFcn(app, @openGuide, true);
            app.StepbystepguideMenu.Text = 'Step-by-step guide';
            app.ContactMenu = uimenu(app.HelpMenu);
            app.ContactMenu.MenuSelectedFcn = createCallbackFcn(app, @openContact, true);
            app.ContactMenu.Text = 'Contact';
            % Show the figure after all components are created
            app.HRVEvaluationToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRVEvaluationToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @setupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRVEvaluationToolUIFigure)
        end
    end
end